classdef SurfNIRS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SurfNIRSUIFigure          matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        ImportMenu                matlab.ui.container.Menu
        AnalyzIRMenu              matlab.ui.container.Menu
        BIDSFolderMenu            matlab.ui.container.Menu
        matMenu                   matlab.ui.container.Menu
        RawNIRxMenu               matlab.ui.container.Menu
        Homer3Menu                matlab.ui.container.Menu
        groupResultsmatMenu       matlab.ui.container.Menu
        nirsHomer2Menu            matlab.ui.container.Menu
        BIDSFolderMenu_2          matlab.ui.container.Menu
        nirsMenu                  matlab.ui.container.Menu
        CloseAllMenu              matlab.ui.container.Menu
        PreloadAllMenu            matlab.ui.container.Menu
        ExitMenu                  matlab.ui.container.Menu
        InterfaceMenu             matlab.ui.container.Menu
        FullscreenModeMenu        matlab.ui.container.Menu
        InteractiveHighlightMenu  matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        VisitGitHubWebpageMenu    matlab.ui.container.Menu
        ReportIssuesMenu          matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        ClickModeToggleButton     matlab.ui.control.StateButton
        DisableAllButton          matlab.ui.control.Button
        EnableAllButton           matlab.ui.control.Button
        LabelTitle                matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        ButtonDown                matlab.ui.control.Button
        ButtonUp                  matlab.ui.control.Button
        ButtonRight               matlab.ui.control.Button
        ButtonLeft                matlab.ui.control.Button
        NavigateacrossandwithinrunsLabel  matlab.ui.control.Label
        DatasetsLabel             matlab.ui.control.Label
        SurfNIRSLabel             matlab.ui.control.Label
        Tree                      matlab.ui.container.Tree
        UIAxes_Montage            matlab.ui.control.UIAxes
        UIAxes_Freq               matlab.ui.control.UIAxes
        UIAxes_CorrMat            matlab.ui.control.UIAxes
        UIAxes_Autocorr           matlab.ui.control.UIAxes
        UIAxes_Main               matlab.ui.control.UIAxes
    end

    properties ( SetAccess = private, GetAccess = public )
        Debug = false
        Version = 1.1
        SessionInfo
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SessionInfo = SurfNIRS.SessionInfo(app);
            SurfNIRS.startup(app);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            SurfNIRS.callbacks.TreeSelectionChanged(app, event);
        end

        % Button pushed function: ButtonLeft
        function ButtonLeftPushed(app, event)
            SurfNIRS.callbacks.ButtonLeftPushed(app, event);
        end

        % Button pushed function: ButtonRight
        function ButtonRightPushed(app, event)
            SurfNIRS.callbacks.ButtonRightPushed(app, event);
        end

        % Button pushed function: ButtonUp
        function ButtonUpPushed(app, event)
            SurfNIRS.callbacks.ButtonUpPushed(app, event);
        end

        % Button pushed function: ButtonDown
        function ButtonDownPushed(app, event)
            SurfNIRS.callbacks.ButtonDownPushed(app, event);
        end

        % Key press function: SurfNIRSUIFigure
        function SurfNIRSUIFigureKeyPress(app, event)
            SurfNIRS.callbacks.SurfNIRSUIFigureKeyPress(app, event);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: VisitGitHubWebpageMenu
        function VisitGitHubWebpageMenuSelected(app, event)
            web("https://github.com/Western-SPRINT/SurfNIRS")
        end

        % Menu selected function: ReportIssuesMenu
        function ReportIssuesMenuSelected(app, event)
            web("https://github.com/Western-SPRINT/SurfNIRS/issues")
        end

        % Menu selected function: BIDSFolderMenu
        function BIDSFolderMenuSelected(app, event)
            SurfNIRS.callbacks.Import_AnalyzIR_BIDS(app, event);
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            SurfNIRS.callbacks.Import_AnalyzIR_mat(app, event);
        end

        % Menu selected function: groupResultsmatMenu
        function groupResultsmatMenuSelected(app, event)
            SurfNIRS.callbacks.Import_Homer3_groupResults(app, event);
        end

        % Menu selected function: BIDSFolderMenu_2
        function BIDSFolderMenu_2Selected(app, event)
            SurfNIRS.callbacks.Import_dotnirs_BIDS(app, event);
        end

        % Menu selected function: nirsMenu
        function nirsMenuSelected(app, event)
            SurfNIRS.callbacks.Import_dotnirs(app, event);
        end

        % Menu selected function: CloseAllMenu
        function CloseAllMenuSelected(app, event)
            SurfNIRS.callbacks.CloseAllData(app, event);
        end

        % Menu selected function: PreloadAllMenu
        function PreloadAllMenuSelected(app, event)
            SurfNIRS.callbacks.PreLoadSelected(app, event);
        end

        % Menu selected function: FullscreenModeMenu
        function FullscreenModeMenuSelected(app, event)
            app.FullscreenModeMenu.Checked = ~app.FullscreenModeMenu.Checked;
            if app.FullscreenModeMenu.Checked
                app.SurfNIRSUIFigure.WindowState = "fullscreen";
            else
                app.SurfNIRSUIFigure.WindowState = "normal";
            end
        end

        % Value changed function: ClickModeToggleButton
        function ClickModeToggleButtonValueChanged(app, event)
            value = app.ClickModeToggleButton.Value;
            SurfNIRS.callbacks.ClickModeToggleButtonValueChanged(app, value);
        end

        % Button pushed function: EnableAllButton
        function EnableAllButtonPushed(app, event)
            SurfNIRS.callbacks.EnableAllButtonPushed(app);
        end

        % Button pushed function: DisableAllButton
        function DisableAllButtonPushed(app, event)
            SurfNIRS.callbacks.DisableAllButtonPushed(app);
        end

        % Menu selected function: InteractiveHighlightMenu
        function InteractiveHighlightMenuSelected(app, event)
            app.InteractiveHighlightMenu.Checked = ~app.InteractiveHighlightMenu.Checked;
        end

        % Menu selected function: RawNIRxMenu
        function RawNIRxMenuSelected(app, event)
            SurfNIRS.callbacks.Import_AnalyzIR_NIRx(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SurfNIRSUIFigure = uifigure('Visible', 'off');
            app.SurfNIRSUIFigure.Name = 'SurfNIRS';
            app.SurfNIRSUIFigure.Icon = fullfile(pathToMLAPP, 'Images', 'logo_icon.png');
            app.SurfNIRSUIFigure.KeyPressFcn = createCallbackFcn(app, @SurfNIRSUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.SurfNIRSUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.AnalyzIRMenu = uimenu(app.ImportMenu);
            app.AnalyzIRMenu.Tooltip = {'nirs.core.Data'};
            app.AnalyzIRMenu.Text = 'AnalyzIR';
            app.BIDSFolderMenu = uimenu(app.AnalyzIRMenu);
            app.BIDSFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @BIDSFolderMenuSelected, true);
            app.BIDSFolderMenu.Text = 'BIDS Folder';
            app.matMenu = uimenu(app.AnalyzIRMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.RawNIRxMenu = uimenu(app.AnalyzIRMenu);
            app.RawNIRxMenu.MenuSelectedFcn = createCallbackFcn(app, @RawNIRxMenuSelected, true);
            app.RawNIRxMenu.Text = 'Raw NIRx';
            app.Homer3Menu = uimenu(app.ImportMenu);
            app.Homer3Menu.Tooltip = {'d, dod, and dc'};
            app.Homer3Menu.Text = 'Homer3';
            app.groupResultsmatMenu = uimenu(app.Homer3Menu);
            app.groupResultsmatMenu.MenuSelectedFcn = createCallbackFcn(app, @groupResultsmatMenuSelected, true);
            app.groupResultsmatMenu.Text = 'groupResults.mat';
            app.nirsHomer2Menu = uimenu(app.ImportMenu);
            app.nirsHomer2Menu.Tooltip = {'d, dod, and dc'};
            app.nirsHomer2Menu.Text = '.nirs / Homer2';
            app.BIDSFolderMenu_2 = uimenu(app.nirsHomer2Menu);
            app.BIDSFolderMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BIDSFolderMenu_2Selected, true);
            app.BIDSFolderMenu_2.Text = 'BIDS Folder';
            app.nirsMenu = uimenu(app.nirsHomer2Menu);
            app.nirsMenu.MenuSelectedFcn = createCallbackFcn(app, @nirsMenuSelected, true);
            app.nirsMenu.Text = '.nirs';
            app.CloseAllMenu = uimenu(app.FileMenu);
            app.CloseAllMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAllMenuSelected, true);
            app.CloseAllMenu.Text = 'Close All';
            app.PreloadAllMenu = uimenu(app.FileMenu);
            app.PreloadAllMenu.MenuSelectedFcn = createCallbackFcn(app, @PreloadAllMenuSelected, true);
            app.PreloadAllMenu.Separator = 'on';
            app.PreloadAllMenu.Text = 'Preload All';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.InterfaceMenu = uimenu(app.SurfNIRSUIFigure);
            app.InterfaceMenu.Text = 'Interface';
            app.FullscreenModeMenu = uimenu(app.InterfaceMenu);
            app.FullscreenModeMenu.MenuSelectedFcn = createCallbackFcn(app, @FullscreenModeMenuSelected, true);
            app.FullscreenModeMenu.Text = 'Fullscreen Mode';
            app.InteractiveHighlightMenu = uimenu(app.InterfaceMenu);
            app.InteractiveHighlightMenu.MenuSelectedFcn = createCallbackFcn(app, @InteractiveHighlightMenuSelected, true);
            app.InteractiveHighlightMenu.Checked = 'on';
            app.InteractiveHighlightMenu.Text = 'Interactive Highlight';
            app.HelpMenu = uimenu(app.SurfNIRSUIFigure);
            app.HelpMenu.Text = 'Help';
            app.VisitGitHubWebpageMenu = uimenu(app.HelpMenu);
            app.VisitGitHubWebpageMenu.MenuSelectedFcn = createCallbackFcn(app, @VisitGitHubWebpageMenuSelected, true);
            app.VisitGitHubWebpageMenu.Text = 'Visit GitHub Webpage';
            app.ReportIssuesMenu = uimenu(app.HelpMenu);
            app.ReportIssuesMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportIssuesMenuSelected, true);
            app.ReportIssuesMenu.Text = 'Report Issues';
            app.GridLayout = uigridlayout(app.SurfNIRSUIFigure);
            app.UIAxes_Main = uiaxes(app.GridLayout);
            app.UIAxes_Main.XTick = [];
            app.UIAxes_Main.YTick = [];
            app.UIAxes_Autocorr = uiaxes(app.GridLayout);
            app.UIAxes_Autocorr.XTick = [];
            app.UIAxes_Autocorr.YTick = [];
            app.UIAxes_CorrMat = uiaxes(app.GridLayout);
            app.UIAxes_CorrMat.XTick = [];
            app.UIAxes_CorrMat.YTick = [];
            app.UIAxes_Freq = uiaxes(app.GridLayout);
            app.UIAxes_Freq.XTick = [];
            app.UIAxes_Freq.YTick = [];
            app.UIAxes_Montage = uiaxes(app.GridLayout);
            app.UIAxes_Montage.XTick = [];
            app.UIAxes_Montage.YTick = [];
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SurfNIRSLabel = uilabel(app.GridLayout);
            app.SurfNIRSLabel.Text = 'SurfNIRS';
            app.DatasetsLabel = uilabel(app.GridLayout);
            app.DatasetsLabel.Text = 'Datasets';
            app.NavigateacrossandwithinrunsLabel = uilabel(app.GridLayout);
            app.NavigateacrossandwithinrunsLabel.Text = {'Navigate across(←/→)'; 'and within(↑/↓) runs:'};
            app.ButtonLeft = uibutton(app.GridLayout, 'push');
            app.ButtonLeft.ButtonPushedFcn = createCallbackFcn(app, @ButtonLeftPushed, true);
            app.ButtonLeft.Text = '←';
            app.ButtonRight = uibutton(app.GridLayout, 'push');
            app.ButtonRight.ButtonPushedFcn = createCallbackFcn(app, @ButtonRightPushed, true);
            app.ButtonRight.Text = '→';
            app.ButtonUp = uibutton(app.GridLayout, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpPushed, true);
            app.ButtonUp.Text = '↑';
            app.ButtonDown = uibutton(app.GridLayout, 'push');
            app.ButtonDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonDownPushed, true);
            app.ButtonDown.Text = '↓';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'logo.png');
            app.LabelTitle = uilabel(app.GridLayout);
            app.LabelTitle.Text = '';
            app.EnableAllButton = uibutton(app.GridLayout, 'push');
            app.EnableAllButton.ButtonPushedFcn = createCallbackFcn(app, @EnableAllButtonPushed, true);
            app.EnableAllButton.Text = 'Enable All';
            app.DisableAllButton = uibutton(app.GridLayout, 'push');
            app.DisableAllButton.ButtonPushedFcn = createCallbackFcn(app, @DisableAllButtonPushed, true);
            app.DisableAllButton.Text = 'Disable All';
            app.ClickModeToggleButton = uibutton(app.GridLayout, 'state');
            app.ClickModeToggleButton.ValueChangedFcn = createCallbackFcn(app, @ClickModeToggleButtonValueChanged, true);
            app.ClickModeToggleButton.Text = 'Click Mode: Toggle';
            % Show the figure after all components are created
            app.SurfNIRSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SurfNIRS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SurfNIRSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SurfNIRSUIFigure)
        end
    end
end